classdef Satellite_image_change_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        PushTool2                matlab.ui.container.toolbar.PushTool
        TabGroup                 matlab.ui.container.TabGroup
        LoadedPicturesTab        matlab.ui.container.Tab
        DeleteshownpictureLabel  matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        RUNButton                matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        timelineSlider           matlab.ui.control.Slider
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        FeatureDetectionTab      matlab.ui.container.Tab
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        OverlappingPicturesTab   matlab.ui.container.Tab
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        UIAxes3_2                matlab.ui.control.UIAxes
        ChangeRecognitionTab     matlab.ui.container.Tab
        Image                    matlab.ui.control.Image
        UIAxes                   matlab.ui.control.UIAxes
        TimeLapseTab             matlab.ui.container.Tab
        SpeedSlider              matlab.ui.control.Slider
        SpeedSliderLabel         matlab.ui.control.Label
        ShowchangesCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        pic;
        index;
        index_feature;
        index_rotated;
    end
    
    methods (Access = private)
        
        function change_timeline(app)
            labels=strings(1,4);ticks=zeros(1,4);minorticks=zeros(1,length(app.pic.pic));j=1;
            for i=round(linspace(1,length(app.pic.pic),4))
                ticks(1,j)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
                labels(1,j)=datestr(app.pic.pic{1,i}.date);
                j=j+1;
            end
            for i=1:length(app.pic.pic)
                minorticks(1,i)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
            end
            app.timelineSlider.Limits = [ticks(1),ticks(length(ticks))];
            app.timelineSlider.Value = ticks(1);
            app.timelineSlider.MajorTicks = ticks;
            app.timelineSlider.MajorTickLabels = labels;
            app.timelineSlider.MinorTicks = minorticks;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            dir={};
            if(ispref('difference_detector','picture_dir'))
                dir = {getpref('difference_detector','picture_dir')};
            end
            [filenames,path] = uigetfile({'*','All Files'},'Select the pictures',dir{:},'MultiSelect','on');
            figure(app.UIFigure)
            if(~isequal(filenames,0))
                setpref('difference_detector','picture_dir',path);
                if(~isempty(app.pic))
                    old_pic = app.pic.get_pic;
                else
                    old_pic = [];
                end
                app.pic = pictures(app,filenames,path,old_pic);
                I = imshow(app.pic.pic{1,1}.picture,'Parent',app.UIAxes2,'XData',[1 app.UIAxes2.Position(3)],'YData',[1 app.UIAxes2.Position(4)]);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                app.index=1;
                app.change_timeline;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.index==1)
                app.index = length(app.pic.pic);
            else
                app.index=app.index-1;
            end
                
            I = imshow(app.pic.pic{1,app.index}.picture,'Parent',app.UIAxes2,'XData',[1 app.UIAxes2.Position(3)],'YData',[1 app.UIAxes2.Position(4)]);
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if(app.index==length(app.pic.pic))
                app.index = 1;
            else
                app.index=app.index+1;
            end 
            I = imshow(app.pic.pic{1,app.index}.picture,'Parent',app.UIAxes2,'XData',[1 app.UIAxes2.Position(3)],'YData',[1 app.UIAxes2.Position(4)]);
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Value changed function: timelineSlider
        function timelineSliderValueChanged(app, event)
            value = app.timelineSlider.Value;
            [d,ix] = min(abs(app.timelineSlider.MinorTicks-value));
            app.timelineSlider.Value = app.timelineSlider.MinorTicks(ix);
            app.index = ix;
            I = imshow(app.pic.pic{1,app.index}.picture,'Parent',app.UIAxes2,'XData',[1 app.UIAxes2.Position(3)],'YData',[1 app.UIAxes2.Position(4)]);
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            app.pic=[];
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.timelineSlider.MajorTickLabels = {'a', 'b', 'c', 'd', 'e', 'f', 'g', 'h', 'i', 'j', 'k'};
            cla(app.UIAxes2);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.pic = app.pic.run_change_detection;
            app.index_feature = 1;
            I = app.pic.pic{1,app.index_feature}.picture;
            if(app.Switch.Value=="SIFT")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.SURF.validPts,'x');
            elseif(app.Switch.Value=="ORB")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.ORB.validPts,'x');
            end
            imshow(I,'Parent',app.UIAxes3,'XData',[1 app.UIAxes3.Position(3)],'YData',[1 app.UIAxes3.Position(4)]);
            app.index_rotated = 1;
            I = app.pic.pic{1,app.index_rotated}.picture;
            imshow(I,'Parent',app.UIAxes3_2,'XData',[1 app.UIAxes3_2.Position(3)],'YData',[1 app.UIAxes3_2.Position(4)])
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if(app.index_feature==length(app.pic.pic))
                app.index_feature = 1;
            else
                app.index_feature=app.index_feature+1;
            end 
            I = app.pic.pic{1,app.index_feature}.picture;
            if(app.Switch.Value=="SIFT")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.SURF.validPts,'x');
            elseif(app.Switch.Value=="ORB")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.ORB.validPts,'x');
            end
            imshow(I,'Parent',app.UIAxes3,'XData',[1 app.UIAxes3.Position(3)],'YData',[1 app.UIAxes3.Position(4)]);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if(app.index_feature==1)
                app.index_feature = length(app.pic.pic);
            else
                app.index_feature=app.index_feature-1;
            end
            I = app.pic.pic{1,app.index_feature}.picture;
            if(app.Switch.Value=="SIFT")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.SURF.validPts,'x');
            elseif(app.Switch.Value=="ORB")
                I = insertMarker(I,app.pic.pic{1,app.index_feature}.ORB.validPts,'x');
            end
            imshow(I,'Parent',app.UIAxes3,'XData',[1 app.UIAxes3.Position(3)],'YData',[1 app.UIAxes3.Position(4)]);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if(app.index_rotated==1)
                app.index_rotated = length(app.pic.pic);
            else
                app.index_rotated=app.index_rotated-1;
            end
            if(app.index_rotated==1)
                I = app.pic.pic{1,app.index_rotated}.picture;
            else
                I = app.pic.pic{1,app.index_rotated}.SURF.rotated_picture;
            end
            imshow(I,'Parent',app.UIAxes3_2,'XData',[1 app.UIAxes3_2.Position(3)],'YData',[1 app.UIAxes3_2.Position(4)]);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if(app.index_rotated==length(app.pic.pic))
                app.index_rotated = 1;
            else
                app.index_rotated=app.index_rotated+1;
            end
            if(app.index_rotated==1)
                I = app.pic.pic{1,app.index_rotated}.picture;
            else
                I = app.pic.pic{1,app.index_rotated}.SURF.rotated_picture;
            end
            imshow(I,'Parent',app.UIAxes3_2,'XData',[1 app.UIAxes3_2.Position(3)],'YData',[1 app.UIAxes3_2.Position(4)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = '126477.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadedPicturesTab = uitab(app.TabGroup);
            app.LoadedPicturesTab.Title = 'Loaded Pictures';
            app.UIAxes2 = uiaxes(app.LoadedPicturesTab);
            app.Button = uibutton(app.LoadedPicturesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'arrows-left-slider-31125.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.LoadedPicturesTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'arrows-right-slider-31125.png';
            app.Button_2.Text = '';
            app.timelineSlider = uislider(app.LoadedPicturesTab);
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.timelineSlider.MajorTickLabels = {'a', 'b', 'c', 'd', 'e', 'f', 'g', 'h', 'i', 'j', 'k'};
            app.timelineSlider.ValueChangedFcn = createCallbackFcn(app, @timelineSliderValueChanged, true);
            app.timelineSlider.MinorTicks = [];
            app.Switch = uiswitch(app.LoadedPicturesTab, 'slider');
            app.Switch.Items = {'SIFT', 'ORB'};
            app.Switch.Value = 'SIFT';
            app.Label = uilabel(app.LoadedPicturesTab);
            app.TextArea = uitextarea(app.LoadedPicturesTab);
            app.TextArea.Value = {'Advantages:'; ''; ''; 'Disadvantages:'};
            app.RUNButton = uibutton(app.LoadedPicturesTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Button_3 = uibutton(app.LoadedPicturesTab, 'push');
            app.Button_3.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.Button_3.Text = '';
            app.DeleteshownpictureLabel = uilabel(app.LoadedPicturesTab);
            app.DeleteshownpictureLabel.Text = 'Delete shown picture';
            app.FeatureDetectionTab = uitab(app.TabGroup);
            app.FeatureDetectionTab.Title = 'Feature Detection';
            app.UIAxes3 = uiaxes(app.FeatureDetectionTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Button_4 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'arrows-right-slider-31125.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'arrows-left-slider-31125.png';
            app.Button_5.Text = '';
            app.OverlappingPicturesTab = uitab(app.TabGroup);
            app.OverlappingPicturesTab.Title = 'Overlapping Pictures';
            app.UIAxes3_2 = uiaxes(app.OverlappingPicturesTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.Button_6 = uibutton(app.OverlappingPicturesTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'arrows-right-slider-31125.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.OverlappingPicturesTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'arrows-left-slider-31125.png';
            app.Button_7.Text = '';
            app.ChangeRecognitionTab = uitab(app.TabGroup);
            app.ChangeRecognitionTab.Title = 'Change Recognition';
            app.UIAxes = uiaxes(app.ChangeRecognitionTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.ChangeRecognitionTab);
            app.TimeLapseTab = uitab(app.TabGroup);
            app.TimeLapseTab.Title = 'Time Lapse';
            app.ShowchangesCheckBox = uicheckbox(app.TimeLapseTab);
            app.ShowchangesCheckBox.Text = '  Show changes';
            app.SpeedSliderLabel = uilabel(app.TimeLapseTab);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.TimeLapseTab);
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Satellite_image_change_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end