classdef Satellite_image_change_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SatelliteImageChangeRecognitionUIFigure  matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        PushTool2              matlab.ui.container.toolbar.PushTool
        Panel                  matlab.ui.container.Panel
        Image3                 matlab.ui.control.Image
        UploadimagesLabel      matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        TabGroup               matlab.ui.container.TabGroup
        LoadedImagesTab        matlab.ui.container.Tab
        Switch_6               matlab.ui.control.Switch
        DeleteshownimageLabel  matlab.ui.control.Label
        Button_3               matlab.ui.control.Button
        RUNButton              matlab.ui.control.Button
        Switch                 matlab.ui.control.Switch
        timelineSlider         matlab.ui.control.Slider
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        FeatureDetectionTab    matlab.ui.container.Tab
        Switch_2               matlab.ui.control.Switch
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        TimeLapsOverlayDifferenceHighlightingTab  matlab.ui.container.Tab
        Switch_4               matlab.ui.control.Switch
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        ChangeDetectionTab     matlab.ui.container.Tab
        Switch_3               matlab.ui.control.Switch
        Button_9               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        DensityofChangeTab     matlab.ui.container.Tab
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Switch_5               matlab.ui.control.Switch
    end

    
    properties (Access = private)
        pic;
        index;
        index_feature;
        index_rotated;
        index_changed;
        index_density
        loaded_pictures;
        feature_pictures;
        matched_feature_pictures;
        rotated_pictures;
        overlayed_pictures;
        overlayed_changes;
        time_specific_changes;
        histo;
        histo_2;
        heat;
        canopy;
    end
    
    methods (Access = private)
        
        function change_timeline(app)
            labels=strings(1,4);ticks=zeros(1,4);minorticks=zeros(1,length(app.pic.pic));j=1;
            for i=round(linspace(1,length(app.pic.pic),4))
                ticks(1,j)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
                labels(1,j)=datestr(app.pic.pic{1,i}.date);
                j=j+1;
            end
            for i=1:length(app.pic.pic)
                minorticks(1,i)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
            end
            app.timelineSlider.Limits = [ticks(1),ticks(length(ticks))];
            app.timelineSlider.Value = ticks(1);
            app.timelineSlider.MajorTicks = ticks;
            app.timelineSlider.MajorTickLabels = labels;
            app.timelineSlider.MinorTicks = minorticks;
        end
        
        function changed_points = get_changed_points(~,I)
            hsv_matrix = rgb2hsv(I);
            reshaped = reshape(hsv_matrix,[size(hsv_matrix,1)*size(hsv_matrix,2),3]);
            cond = reshaped(:,1)<=0.877 & reshaped(:,1)>=0.804 & reshaped(:,3)<=0.9;
            pink_matrix = reshaped(cond,:);
            max_V=max(pink_matrix(:,3));
            if(isempty(max_V))
                max_V=1;
            end
            [row,col] = find((hsv_matrix(:,:,1)<=0.877 & hsv_matrix(:,:,1)>=0.804 & hsv_matrix(:,:,2)<=0.75 &...
                hsv_matrix(:,:,2)>=0.3 & hsv_matrix(:,:,3)<=max_V & hsv_matrix(:,:,3)>=0.6*max_V) | ...
                (hsv_matrix(:,:,1)<=0.414 & hsv_matrix(:,:,1)>=0.280 & hsv_matrix(:,:,2)<=0.733 &...
                hsv_matrix(:,:,2)>=0.438 & hsv_matrix(:,:,3)<=1 & hsv_matrix(:,:,3)>=0.854));
            changed_points=[col,row];
%             changed_points=zeros(size(I,1)*size(I,2),2);
%             for i=1:size(I,1)
%                 parfor j=1:size(I,2)
%                     if((hsv_matrix(i,j,1)<=0.877 && hsv_matrix(i,j,1)>=0.804 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0.8 && hsv_matrix(i,j,2)<=1 && hsv(i,j,2)>=0) || ...
%                             (hsv_matrix(i,j,1)<=0.414 && hsv_matrix(i,j,1)>=0.280 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0.8 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0))
%                         changed_points(i+j,:)=[j,i];
%                     end
%                 end
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel.Position = [2,2,913,559];
            app.Panel.Visible = true;
            app.Panel.Enable = 'on';
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.Panel.Visible = false;
            dir={};
            if(ispref('difference_detector','picture_dir'))
                dir = {getpref('difference_detector','picture_dir')};
            end
            [filenames,path] = uigetfile({'*','All Files'},'Select the pictures',dir{:},'MultiSelect','on');
            figure(app.SatelliteImageChangeRecognitionUIFigure)
            if(~isequal(filenames,0))
                setpref('difference_detector','picture_dir',path);
                if(~isempty(app.pic))
                    old_pic = app.pic.get_pic;
                else
                    old_pic = [];
                end
                progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Please Wait','Message','Pretty pictures are being loaded');
                app.pic = pictures(app,filenames,path,old_pic);
                app.loaded_pictures = cell(1,length(app.pic.pic));
                for i=1:length(app.loaded_pictures)
                    app.loaded_pictures{1,i}.Panel = uipanel(app.LoadedImagesTab);
                    app.loaded_pictures{1,i}.Panel.Position = [150 86 587 375]; %y = 86
                    app.loaded_pictures{1,i}.Panel.BorderType = 'none';
                    app.loaded_pictures{1,i}.UIAxes = uiaxes(app.loaded_pictures{1,i}.Panel);
                    app.loaded_pictures{1,i}.UIAxes.Position = [0 0 app.loaded_pictures{1,i}.Panel.Position(3:4)];
                    disableDefaultInteractivity(app.loaded_pictures{1,i}.UIAxes)
                    app.loaded_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                    title(app.loaded_pictures{1,i}.UIAxes, []);
                    xlabel(app.loaded_pictures{1,i}.UIAxes, []);
                    ylabel(app.loaded_pictures{1,i}.UIAxes, []);
                    app.loaded_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                    app.loaded_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                    app.loaded_pictures{1,i}.UIAxes.Visible = 'off';
                    I = imshow(app.pic.pic{1,i}.picture,'Parent',app.loaded_pictures{1,i}.UIAxes,...
                        'XData',[1 app.loaded_pictures{1,i}.UIAxes.Position(3)],'YData',[1 app.loaded_pictures{1,i}.UIAxes.Position(4)]);
                    app.loaded_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                    app.loaded_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                    app.loaded_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                    if(i==1)
                        app.loaded_pictures{1,i}.Panel.Visible=true;
                        drawnow;
                    else
                        app.loaded_pictures{1,i}.Panel.Visible=false;
                    end
                    progress.Value = i/(length(app.loaded_pictures));
                end
                app.index=1;
                progress.Message = 'Changing Timeline';
                app.change_timeline;
                close(progress);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if(selectedTab.Title~="Loaded Pictures" && isempty(app.pic.pic{1,1}.SURF) && isempty(app.pic.pic{1,1}.SIFT))
                app.TabGroup.SelectedTab = app.TabGroup.Children(1,1);
                uialert(app.SatelliteImageChangeRecognitionUIFigure,"Difference detection must have been performed. Please click the RUN-Button.","Run Change Detection");
            elseif(selectedTab.Title=="Density of Change" && app.Switch_6.Value=="Off")
                app.TabGroup.SelectedTab = app.TabGroup.Children(1,1);
                uialert(app.SatelliteImageChangeRecognitionUIFigure,"Density of Change was not computed","Tab Selection");
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            for i=1:length(app.loaded_pictures)
                app.loaded_pictures{1,i}.Panel.Visible=false;
            end
            drawnow;
            if(app.index==1)
                app.index = length(app.pic.pic);
            else
                app.index=app.index-1;
            end 
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
            drawnow;
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            app.PushToolClicked(event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            for i=1:length(app.loaded_pictures)
                app.loaded_pictures{1,i}.Panel.Visible=false;
            end
            drawnow;
            if(app.index==length(app.pic.pic))
                app.index = 1;
            else
                app.index=app.index+1;
            end 
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
            drawnow;
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if(app.index~=0)    
                for i=app.index:length(app.pic.pic)-1
                    app.pic.pic{1,i}=app.pic.pic{1,i+1};
                    app.loaded_pictures{1,i}=app.loaded_pictures{1,i+1};
                end
                app.pic.pic=app.pic.pic(1,1:end-1);
                app.loaded_pictures=app.loaded_pictures(1,1:end-1);
                app.loaded_pictures{1,app.index}.Panel.Visible=false;
                if(app.index==1)
                    app.index = length(app.pic.pic);
                else
                    app.index=app.index-1;
                end 
                app.loaded_pictures{1,app.index}.Panel.Visible=true;
                app.change_timeline;
                app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
            end
        end

        % Value changed function: timelineSlider
        function timelineSliderValueChanged(app, event)
            value = app.timelineSlider.Value;
            [d,ix] = min(abs(app.timelineSlider.MinorTicks-value));
            app.timelineSlider.Value = app.timelineSlider.MinorTicks(ix);
            app.loaded_pictures{1,app.index}.Panel.Visible=false;
            app.index = ix;
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Deleting all Images','Indeterminate','on');
            app.TabGroup.SelectedTab = app.TabGroup.Children(1,1);
            app.pic=[];
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [];
            app.timelineSlider.MajorTickLabels = {};
            app.loaded_pictures=[];
            app.feature_pictures=[];
            app.matched_feature_pictures=[];
            app.rotated_pictures=[];
            close(progress);
            app.Panel.Visible = true;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Please Wait','Message','Lalal');
            drawnow;
            app.pic = app.pic.run_change_detection(progress);
            progress.Title = 'Please Wait (Step 3/6)';
            progress.Message = 'Plotting unique Features';
            progress.Value = 0;
            drawnow;
            app.feature_pictures = cell(1,length(app.pic.pic));
            app.matched_feature_pictures = cell(1,length(app.pic.pic));
            for i=1:length(app.pic.pic)
                %% Feature Panels
                app.feature_pictures{1,i}.Panel = uipanel(app.FeatureDetectionTab);
                app.feature_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.feature_pictures{1,i}.Panel.BorderType = 'none';
                app.feature_pictures{1,i}.UIAxes = uiaxes(app.feature_pictures{1,i}.Panel);
                app.feature_pictures{1,i}.UIAxes.Position = [0 0 app.feature_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.feature_pictures{1,i}.UIAxes)
                app.feature_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.feature_pictures{1,i}.UIAxes, []);
                xlabel(app.feature_pictures{1,i}.UIAxes, []);
                ylabel(app.feature_pictures{1,i}.UIAxes, []);
                app.feature_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.feature_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.feature_pictures{1,i}.UIAxes.Visible = 'off';
                %% Matched Feature Panels
                app.matched_feature_pictures{1,i}.Panel = uipanel(app.FeatureDetectionTab);
                app.matched_feature_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.matched_feature_pictures{1,i}.Panel.BorderType = 'none';
                app.matched_feature_pictures{1,i}.UIAxes = uiaxes(app.matched_feature_pictures{1,i}.Panel);
                app.matched_feature_pictures{1,i}.UIAxes.Position = [0 0 app.matched_feature_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.matched_feature_pictures{1,i}.UIAxes)
                app.matched_feature_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.matched_feature_pictures{1,i}.UIAxes, []);
                xlabel(app.matched_feature_pictures{1,i}.UIAxes, []);
                ylabel(app.matched_feature_pictures{1,i}.UIAxes, []);
                app.matched_feature_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.matched_feature_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.matched_feature_pictures{1,i}.UIAxes.Visible = 'off';
                I = app.pic.pic{1,i}.picture;
                if(app.Switch.Value=="SURF")
                    I = insertMarker(I,app.pic.pic{1,i}.SURF.validPts,'x');
                elseif(app.Switch.Value=="SIFT")
                    I = insertMarker(I,app.pic.pic{1,i}.SIFT.features(1:2,:)','x');
                end
                I=imshow(I,'Parent',app.feature_pictures{1,i}.UIAxes,...
                    'XData',[1 app.feature_pictures{1,i}.UIAxes.Position(3)],'YData',[1 app.feature_pictures{1,i}.UIAxes.Position(4)]);
                app.feature_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.feature_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.feature_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(app.Switch.Value=="SURF")
                    if(i~=length(app.pic.pic))
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,i+1}.picture,...
                            app.pic.pic{1,i}.SURF.filteredmatchedPts{1,1},app.pic.pic{1,i}.SURF.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,i+1}.date)]);
                    else
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,1}.picture,...
                            app.pic.pic{1,i}.SURF.filteredmatchedPts{1,1},app.pic.pic{1,i}.SURF.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,1}.date),"(for the sake of completeness)"]);
                    end
                elseif(app.Switch.Value=="SIFT")
                    if(i~=length(app.pic.pic))
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,i+1}.picture,...
                            app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,1},app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,i+1}.date)]);
                    else
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,1}.picture,...
                            app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,1},app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,1}.date),"(for the sake of completeness)"]);
                    end
                end
                app.matched_feature_pictures{1,i}.UIAxes.XLim = [0 IM.XData(2)];
                app.matched_feature_pictures{1,i}.UIAxes.YLim = [0 IM.YData(2)];
                if(i==1)
                    if(app.Switch_2.Value=="Feature Points")
                        app.feature_pictures{1,i}.Panel.Visible=true;
                        app.matched_feature_pictures{1,i}.Panel.Visible=false;
                    elseif(app.Switch_2.Value=="Matched Feature Points")
                        app.feature_pictures{1,i}.Panel.Visible=false;
                        app.matched_feature_pictures{1,i}.Panel.Visible=true;
                    end
                    drawnow;
                else
                    app.feature_pictures{1,i}.Panel.Visible=false;
                    app.matched_feature_pictures{1,i}.Panel.Visible=false;
                end
                progress.Value=i/length(app.pic.pic);
            end
            app.index_feature = 1;
            progress.Title = 'Please Wait (Step 4/6)';
            progress.Message = 'Overlays are being carefully plotted';
            progress.Value = 0;
            app.rotated_pictures = cell(1,length(app.pic.pic));
            for i=1:length(app.pic.pic)
                app.rotated_pictures{1,i}.Panel = uipanel(app.TimeLapsOverlayDifferenceHighlightingTab);
                app.rotated_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.rotated_pictures{1,i}.Panel.BorderType = 'none';
                app.rotated_pictures{1,i}.UIAxes = uiaxes(app.rotated_pictures{1,i}.Panel);
                app.rotated_pictures{1,i}.UIAxes.Position = [0 0 app.rotated_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.rotated_pictures{1,i}.UIAxes)
                app.rotated_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.rotated_pictures{1,i}.UIAxes, []);
                xlabel(app.rotated_pictures{1,i}.UIAxes, []);
                ylabel(app.rotated_pictures{1,i}.UIAxes, []);
                app.rotated_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.rotated_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.rotated_pictures{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                     if(i==1)
                         I = app.pic.pic{1,i}.picture;
                     else
                         I = app.pic.pic{1,i}.SURF.rotated_picture;
                     end
                end
                I = imshow(I,'Parent',app.rotated_pictures{1,i}.UIAxes);
                I.XData = [1 app.rotated_pictures{1,i}.UIAxes.Position(3)];
                I.YData = [1 app.rotated_pictures{1,i}.UIAxes.Position(4)];
                app.rotated_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.rotated_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.rotated_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(i==1)
                    app.rotated_pictures{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.rotated_pictures{1,i}.Panel.Visible=false;
                end
                app.overlayed_pictures{1,i}.Panel = uipanel(app.TimeLapsOverlayDifferenceHighlightingTab);
                app.overlayed_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.overlayed_pictures{1,i}.Panel.BorderType = 'none';
                app.overlayed_pictures{1,i}.UIAxes = uiaxes(app.overlayed_pictures{1,i}.Panel);
                app.overlayed_pictures{1,i}.UIAxes.Position = [0 0 app.overlayed_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.overlayed_pictures{1,i}.UIAxes)
                app.overlayed_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.overlayed_pictures{1,i}.UIAxes, []);
                xlabel(app.overlayed_pictures{1,i}.UIAxes, []);
                ylabel(app.overlayed_pictures{1,i}.UIAxes, []);
                app.overlayed_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.overlayed_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.overlayed_pictures{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                    if(i==1)
                        I = app.pic.pic{1,i}.picture;
                        tit = datestr(app.pic.pic{1,i}.date);
                    elseif(i==2)
                        RA = imref2d(size(app.pic.pic{1,i}.picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SURF.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,1}.picture,RA,app.pic.pic{1,i}.SURF.rotated_picture,RB);
                        app.pic.pic{1,i}.SURF.changed_points = app.get_changed_points(I);
                        tit = join([datestr(app.pic.pic{1,1}.date),"to",datestr(app.pic.pic{1,2}.date)]);
                    else
                        RA = imref2d(size(app.pic.pic{1,i-1}.SURF.rotated_picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SURF.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,i-1}.SURF.rotated_picture,RA,app.pic.pic{1,i}.SURF.rotated_picture,RB,'falsecolor','Scaling','joint');
                        app.pic.pic{1,i}.SURF.changed_points = app.get_changed_points(I);
                        tit = join([datestr(app.pic.pic{1,i-1}.date),"to",datestr(app.pic.pic{1,i}.date)]);
                    end
                elseif(app.Switch.Value=="SIFT")
                    tit="";
                    if(i==1)
                        I = app.pic.pic{1,i}.picture;
                    elseif(i==2)
                        RA = imref2d(size(app.pic.pic{1,i}.picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SIFT.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,1}.picture,RA,app.pic.pic{1,i}.SIFT.rotated_picture,RB);
                    else
                        RA = imref2d(size(app.pic.pic{1,i-1}.SIFT.rotated_picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SIFT.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,i-1}.SIFT.rotated_picture,RA,app.pic.pic{1,i}.SIFT.rotated_picture,RB,'falsecolor','Scaling','joint');
                    end
                end
                I = imshow(I,'Parent',app.overlayed_pictures{1,i}.UIAxes);
                I.XData = [1 app.overlayed_pictures{1,i}.UIAxes.Position(3)];
                I.YData = [1 app.overlayed_pictures{1,i}.UIAxes.Position(4)];
                app.overlayed_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.overlayed_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.overlayed_pictures{1,i}.UIAxes.Title.String = tit;
                if(i==1)
                    app.overlayed_pictures{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.overlayed_pictures{1,i}.Panel.Visible=false;
                end
                progress.Value = i/length(app.pic.pic);
            end
            app.index_rotated = 1;
            [app.pic.pic,Legend] = showChanges(app.pic.pic);
            progress.Title = 'Please Wait (Step 5/6)';
            progress.Message = 'Recognizing Changes';
            progress.Value=0;
            for i=1:length(app.pic.pic)
                app.overlayed_changes{1,i}.Panel = uipanel(app.ChangeDetectionTab);
                app.overlayed_changes{1,i}.Panel.Position = [74,137,731,300];
                app.overlayed_changes{1,i}.Panel.BorderType = 'none';
                app.overlayed_changes{1,i}.UIAxes = uiaxes(app.overlayed_changes{1,i}.Panel);
                app.overlayed_changes{1,i}.UIAxes.Position = [0 0 app.overlayed_changes{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.overlayed_changes{1,i}.UIAxes)
                app.overlayed_changes{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.overlayed_changes{1,i}.UIAxes, []);
                xlabel(app.overlayed_changes{1,i}.UIAxes, []);
                ylabel(app.overlayed_changes{1,i}.UIAxes, []);
                app.overlayed_changes{1,i}.UIAxes.XAxis.TickLabels = {};
                app.overlayed_changes{1,i}.UIAxes.YAxis.TickLabels = {};
                app.overlayed_changes{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                     I = app.pic.pic{1,i}.SURF.time_change_pic;
                end
                I = imshow(I,'Parent',app.overlayed_changes{1,i}.UIAxes);
                I.XData = [1 app.overlayed_changes{1,i}.UIAxes.Position(3)];
                I.YData = [1 app.overlayed_changes{1,i}.UIAxes.Position(4)];
                app.overlayed_changes{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.overlayed_changes{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.overlayed_changes{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                legend(app.overlayed_changes{1,i}.UIAxes,Legend,'Location','southoutside','Orientation','horizontal')
                if(i==1)
                    app.overlayed_changes{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.overlayed_changes{1,i}.Panel.Visible=false;
                end
                app.time_specific_changes{1,i}.Panel = uipanel(app.ChangeDetectionTab);
                app.time_specific_changes{1,i}.Panel.Position = [74,137,731,300];
                app.time_specific_changes{1,i}.Panel.BorderType = 'none';
                app.time_specific_changes{1,i}.UIAxes = uiaxes(app.time_specific_changes{1,i}.Panel);
                app.time_specific_changes{1,i}.UIAxes.Position = [0 0 app.time_specific_changes{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.time_specific_changes{1,i}.UIAxes)
                app.time_specific_changes{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.time_specific_changes{1,i}.UIAxes, []);
                xlabel(app.time_specific_changes{1,i}.UIAxes, []);
                ylabel(app.time_specific_changes{1,i}.UIAxes, []);
                app.time_specific_changes{1,i}.UIAxes.XAxis.TickLabels = {};
                app.time_specific_changes{1,i}.UIAxes.YAxis.TickLabels = {};
                app.time_specific_changes{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                    if(i==1)
                        I = app.pic.pic{1,i}.picture;
                    else
                        I = app.pic.pic{1,i}.SURF.change_picture;
                    end

                end
                I = imshow(I,'Parent',app.time_specific_changes{1,i}.UIAxes);
                I.XData = [1 app.time_specific_changes{1,i}.UIAxes.Position(3)];
                I.YData = [1 app.time_specific_changes{1,i}.UIAxes.Position(4)];
                app.time_specific_changes{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.time_specific_changes{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.time_specific_changes{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(i==1)
                    app.time_specific_changes{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.time_specific_changes{1,i}.Panel.Visible=false;
                end
                app.histo{1,i}.Panel = uipanel(app.ChangeDetectionTab);
                app.histo{1,i}.Panel.Position = [74,11,731,127];
                app.histo{1,i}.Panel.BorderType = 'none';
                app.histo{1,i}.UIAxes = uiaxes(app.histo{1,i}.Panel);
                app.histo{1,i}.UIAxes.Position = [0 0 app.histo{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.histo{1,i}.UIAxes)
                app.histo{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.histo{1,i}.UIAxes, []);
                app.histo{1,i}.UIAxes.Title.Visible = 'off';
%                 xlabel(app.histo{1,i}.UIAxes, []);
%                 ylabel(app.histo{1,i}.UIAxes, []);
%                 app.histo{1,i}.UIAxes.XAxis.TickLabels = {};
%                 app.histo{1,i}.UIAxes.YAxis.TickLabels = {};
%                 app.histo{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                    hold(app.histo{1,i}.UIAxes, "off");
                    for j=1:i
                        % create histograms
                        % I = histogram(app.histo{1,i}.UIAxes,app.pic.pic{1,j}.Histo.only_Data,'Normalization',"cdf",'BinEdges',edges,"FaceColor",app.pic.pic{1,j}.SURF.change_color/255);
                        if j>1
                            app.pic.pic{1,j}.Histo.Data(1:end)=j-1;
                        else
                            app.pic.pic{1,j}.Histo.Data(1:end)=[];
                        end
                        if j<10
                            I = histogram(app.histo{1,i}.UIAxes,app.pic.pic{1,j}.Histo.Data,0.9:0.2:length(app.pic.pic)-1+0.2,"FaceColor",(app.pic.pic{1,1}.color_hist{length(app.pic.pic{1,1}.color_hist)-length(app.pic.pic)+j})/255);
                        else
                            I = histogram(app.histo{1,i}.UIAxes,app.pic.pic{1,j}.Histo.Data,0.9:0.2:length(app.pic.pic)-1+0.2,"FaceColor",[randi([0,255],1,1), randi([0,255],1,1), randi([0,255],1,1)]/255);
                        end
                        
                        if j==1
                            hold(app.histo{1,i}.UIAxes, "on");
                        end
                    end
                    hold(app.histo{1,i}.UIAxes, "off");
                end
%                 I.XData = [1 app.histo{1,i}.UIAxes.Position(3)];
%                 I.YData = [1 app.histo{1,i}.UIAxes.Position(4)];
%                 app.histo{1,i}.UIAxes.XLim = [0 I.XData(2)];
%                 app.histo{1,i}.UIAxes.YLim = [0 I.YData(2)];
%                 app.histo{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(i==1)
                    app.histo{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.histo{1,i}.Panel.Visible=false;
                end
                
                app.histo_2{1,i}.Panel = uipanel(app.ChangeDetectionTab);
                app.histo_2{1,i}.Panel.Position = [74,11,731,127];
                app.histo_2{1,i}.Panel.BorderType = 'none';
                app.histo_2{1,i}.UIAxes = uiaxes(app.histo_2{1,i}.Panel);
                app.histo_2{1,i}.UIAxes.Position = [0 0 app.histo_2{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.histo_2{1,i}.UIAxes)
                app.histo_2{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.histo_2{1,i}.UIAxes, []);
                app.histo_2{1,i}.UIAxes.Title.Visible = 'off';
%                 xlabel(app.histo{1,i}.UIAxes, []);
%                 ylabel(app.histo{1,i}.UIAxes, []);
%                 app.histo{1,i}.UIAxes.XAxis.TickLabels = {};
%                 app.histo{1,i}.UIAxes.YAxis.TickLabels = {};
%                 app.histo{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                    hold(app.histo_2{1,i}.UIAxes, "off");
                   
                    for j=1:i
                        % create histograms
                        % I = histogram(app.histo{1,i}.UIAxes,app.pic.pic{1,j}.Histo.only_Data,'Normalization',"cdf",'BinEdges',edges,"FaceColor",app.pic.pic{1,j}.SURF.change_color/255);
                        %if j>1
                        %    app.pic.pic{1,j}.Histo.Data(1:end)=j-1;
                        %else
                        %    app.pic.pic{1,j}.Histo.Data(1:end)=[];
                        %end
                        if j<10
                            I = histogram(app.histo_2{1,i}.UIAxes,app.pic.pic{1,j}.Histo.only_Data,0.9:0.2:length(app.pic.pic)-1+0.2,"FaceColor",(app.pic.pic{1,1}.color_hist{length(app.pic.pic{1,1}.color_hist)-length(app.pic.pic)+j})/255);
                        else
                            I = histogram(app.histo_2{1,i}.UIAxes,app.pic.pic{1,j}.Histo.only_Data,0.9:0.2:length(app.pic.pic)-1+0.2,"FaceColor",[randi([0,255],1,1), randi([0,255],1,1), randi([0,255],1,1)]/255);
                        end
                        if j==1
                            hold(app.histo_2{1,i}.UIAxes, "on");
                        end
                    xlabel(app.histo_2{1,i}.UIAxes, []);
                    set(app.histo_2{1,i}.UIAxes,'Xtick',[]);
                    ylabel(app.histo_2{1,i}.UIAxes, ['changed points']);
                    end
                    xlabel(app.histo{1,i}.UIAxes, []);
                    set(app.histo{1,i}.UIAxes,'Xtick',[]);
                    ylabel(app.histo{1,i}.UIAxes, ['changed points']);
                    
                    hold(app.histo_2{1,i}.UIAxes, "off");
                end
%                 I.XData = [1 app.histo{1,i}.UIAxes.Position(3)];
%                 I.YData = [1 app.histo{1,i}.UIAxes.Position(4)];
%                 app.histo{1,i}.UIAxes.XLim = [0 I.XData(2)];
%                 app.histo{1,i}.UIAxes.YLim = [0 I.YData(2)];
%                 app.histo{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(i==1)
                    app.histo_2{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.histo_2{1,i}.Panel.Visible=false;
                end
                
                progress.Value = i/length(app.pic.pic);
            end
            progress.Title = 'Please Wait (Step 6/6)';
            progress.Message = 'Computing Density of Change, this may take a little while';
            progress.Value = 0;
            if(app.Switch_6.Value=="Compute Density Change (beware: slow to compute)")
                for i=1:length(app.pic.pic)-1
                    [app.pic.pic{1,i+1}.Heat, app.pic.pic{1,i+1}.figCanopy] = heatmaps(app.pic.pic{1,i+1}.SURF.rotated_picture, app.pic.pic{1,i+1}.SURF.changed_points, 30);
                    app.heat{1,i}.Panel = uipanel(app.DensityofChangeTab);
                    app.heat{1,i}.Panel.Position = [74,137,731,300];
                    app.heat{1,i}.Panel.BorderType = 'none';
                    app.heat{1,i}.UIAxes = uiaxes(app.heat{1,i}.Panel);
                    app.heat{1,i}.UIAxes2 = uiaxes(app.heat{1,i}.Panel);
                    app.heat{1,i}.UIAxes.Position = [0 0 app.heat{1,i}.Panel.Position(3)/2 app.heat{1,i}.Panel.Position(4)];
                    app.heat{1,i}.UIAxes2.Position = [app.heat{1,i}.Panel.Position(3)/2 0 app.heat{1,i}.Panel.Position(3)/2 app.heat{1,i}.Panel.Position(4)];
                    disableDefaultInteractivity(app.heat{1,i}.UIAxes)
                    disableDefaultInteractivity(app.heat{1,i}.UIAxes2)
                    app.heat{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                    app.heat{1,i}.UIAxes2.Toolbar.Visible = 'off'; 
                    title(app.heat{1,i}.UIAxes, []);
                    xlabel(app.heat{1,i}.UIAxes, []);
                    ylabel(app.heat{1,i}.UIAxes, []);
                    app.heat{1,i}.UIAxes.XAxis.TickLabels = {};
                    app.heat{1,i}.UIAxes.YAxis.TickLabels = {};
                    app.heat{1,i}.UIAxes.Visible = 'off';
                    set(app.heat{1,i}.UIAxes2,'YDir','reverse');
                    set(app.heat{1,i}.UIAxes2,'Xtick',[]);
                    set(app.heat{1,i}.UIAxes2,'Ytick',[]);
                   
                    %app.heat{1,i}.UIAxes2.XAxis.TickLabels = {}; %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if(app.Switch.Value=="SURF")
                        I = imshow(app.pic.pic{1,i+1}.SURF.rotated_picture,'Parent',app.heat{1,i}.UIAxes);
                        contourf(app.heat{1,i}.UIAxes2,app.pic.pic{1,i+1}.Heat{1,1},app.pic.pic{1,i+1}.Heat{1,2},app.pic.pic{1,i+1}.Heat{1,3},'LineColor','none', 'ShowText', 'on');
                    end
                    I.XData = [1 app.heat{1,i}.UIAxes.Position(3)];
                    I.YData = [1 app.heat{1,i}.UIAxes.Position(4)];
                    app.heat{1,i}.UIAxes.XLim = [0 I.XData(2)];
                    app.heat{1,i}.UIAxes.YLim = [0 I.YData(2)];
                    colormap(app.heat{1,i}.UIAxes2, 'cool' )
                    
                    app.canopy{1,i}.Panel = uipanel(app.DensityofChangeTab);
                    app.canopy{1,i}.Panel.Position = [74,137,731,300];
                    app.canopy{1,i}.Panel.BorderType = 'none';
                    app.canopy{1,i}.UIAxes = uiaxes(app.canopy{1,i}.Panel);
                    app.canopy{1,i}.UIAxes.Position = [0 0 app.canopy{1,i}.Panel.Position(3) app.canopy{1,i}.Panel.Position(4)];
                    disableDefaultInteractivity(app.canopy{1,i}.UIAxes)
                    app.canopy{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                    title(app.canopy{1,i}.UIAxes, []);
                    xlabel(app.canopy{1,i}.UIAxes, []);
                    ylabel(app.canopy{1,i}.UIAxes, []);
                    zlabel(app.canopy{1,i}.UIAxes, []);
                    
                    app.canopy{1,i}.UIAxes.XAxis.TickLabels = {};
                    app.canopy{1,i}.UIAxes.YAxis.TickLabels = {};
                    app.canopy{1,i}.UIAxes.ZAxis.TickLabels = {};
                    %set(app.heat{1,i}.UIAxes2,'Ztick',[]);
    
                    if(app.Switch.Value=="SURF")
                        Output = meshCanopy(app.pic.pic{1,i+1}.figCanopy{1,1},app.pic.pic{1,i+1}.figCanopy{1,2},@cool, 100);
                        H(1) = slice(app.canopy{1,i}.UIAxes,repmat(Output{1,6},[1 1 2]),[],[],1);
                        set(H(1),'EdgeColor','none')
                        hold(app.canopy{1,i}.UIAxes,'on');
                        H(2) = mesh(app.canopy{1,i}.UIAxes,Output{1,2},Output{1,3},double(Output{1,4}));
                        hold(app.canopy{1,i}.UIAxes,'off');
                        axis(app.canopy{1,i}.UIAxes,'vis3d');
                        axis(app.canopy{1,i}.UIAxes,'ij');
                        axis(app.canopy{1,i}.UIAxes,'tight');
                        colormap(app.canopy{1,i}.UIAxes, Output{1,5} )
                        colorbar(app.canopy{1,i}.UIAxes)
                        %set(H(2), colorbar);
                        set(app.canopy{1,i}.UIAxes,'Ztick',[]);
                        set(H(1),'CData',Output{1,6});
                        set(H(2),'CData',Output{1,7});
                        
                        chb.UIAxes = colorbar(app.canopy{1,i}.UIAxes);
                        set(chb.UIAxes,'YTick',[])
                        chb.UIAxes.Label.String = 'relative density of change';
                        set(chb.UIAxes, 'ylim', [256 256 + length(Output{1,5})]) %max(app.pic.pic{1,i+1}.figCanopy{1,2}, [], 'all')]
                        caxis(app.canopy{1,i}.UIAxes,[1 length(Output{1,5})])
                    end
                    
                    
                    if(i==1)
                        if(app.Switch_5.Value=="Image vs. Heatmap")
                            app.heat{1,i}.Panel.Visible=true;
                            app.canopy{1,i}.Panel.Visible=false;
                        elseif(app.Switch_5.Value=="Canopy Illustration")
                            app.heat{1,i}.Panel.Visible=false;
                            app.canopy{1,i}.Panel.Visible=true;
                        end
                        drawnow;
                    else
                        app.heat{1,i}.Panel.Visible=false;
                        app.canopy{1,i}.Panel.Visible=false;
                    end
                    progress.Value = i/(length(app.pic.pic)-1);
                end
            end
            app.index_changed = 1;
            app.index_density = 1;
            close(progress);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            for i=1:length(app.pic.pic)
                app.feature_pictures{1,i}.Panel.Visible=false;
                app.matched_feature_pictures{1,i}.Panel.Visible=false;
            end
            if(value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.feature_pictures{1,app.index_feature}.Panel.Visible=false;
            app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=false;
            if(app.index_feature==length(app.pic.pic))
                app.index_feature = 1;
            else
                app.index_feature=app.index_feature+1;
            end
            if(app.Switch_2.Value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(app.Switch_2.Value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.feature_pictures{1,app.index_feature}.Panel.Visible=false;
            app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=false;
            drawnow;
            if(app.index_feature==1)
                app.index_feature = length(app.pic.pic);
            else
                app.index_feature=app.index_feature-1;
            end 
            if(app.Switch_2.Value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(app.Switch_2.Value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            for i=1:length(app.pic.pic)
                app.rotated_pictures{1,i}.Panel.Visible=false;
                app.overlayed_pictures{1,i}.Panel.Visible=false;
            end
            if(value=="Timelapse Overlay")
                app.rotated_pictures{1,app.index_rotated}.Panel.Visible=true;
            elseif(value=="Timelapse Difference Highlighting")
                app.overlayed_pictures{1,app.index_rotated}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=false;
            app.overlayed_pictures{1,app.index_rotated}.Panel.Visible=false;
            if(app.index_rotated==length(app.pic.pic))
                app.index_rotated = 1;
            else
                app.index_rotated=app.index_rotated+1;
            end
            if(app.Switch_4.Value=="Timelapse Overlay")
                app.rotated_pictures{1,app.index_rotated}.Panel.Visible=true;
            elseif(app.Switch_4.Value=="Timelapse Difference Highlighting")
                app.overlayed_pictures{1,app.index_rotated}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=false;
            app.overlayed_pictures{1,app.index_rotated}.Panel.Visible=false;
            if(app.index_rotated==1)
                app.index_rotated = length(app.pic.pic);
            else
                app.index_rotated=app.index_rotated-1;
            end
            if(app.Switch_4.Value=="Timelapse Overlay")
                app.rotated_pictures{1,app.index_rotated}.Panel.Visible=true;
            elseif(app.Switch_4.Value=="Timelapse Difference Highlighting")
                app.overlayed_pictures{1,app.index_rotated}.Panel.Visible=true;
            end
            drawnow;
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            for i=1:length(app.pic.pic)
                app.overlayed_changes{1,i}.Panel.Visible=false;
                app.time_specific_changes{1,i}.Panel.Visible=false;
                app.histo{1,i}.Panel.Visible=false;
            end
            if(value=="Continuous Time Steps Changes")
                app.overlayed_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo{1,app.index_changed}.Panel.Visible=true;
            elseif(value=="Single Time Step Changes")
                app.time_specific_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo_2{1,app.index_changed}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.overlayed_changes{1,app.index_changed}.Panel.Visible=false;
            app.time_specific_changes{1,app.index_changed}.Panel.Visible=false;
            app.histo_2{1,app.index_changed}.Panel.Visible=false;
            if(app.index_changed==length(app.pic.pic))
                app.index_changed = 1;
            else
                app.index_changed=app.index_changed+1;
            end
            if(app.Switch_3.Value=="Continuous Time Steps Changes")
                app.overlayed_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo{1,app.index_changed}.Panel.Visible=true;
            elseif(app.Switch_3.Value=="Single Time Step Changes")
                app.time_specific_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo_2{1,app.index_changed}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.overlayed_changes{1,app.index_changed}.Panel.Visible=false;
            app.time_specific_changes{1,app.index_changed}.Panel.Visible=false;
            app.histo{1,app.index_changed}.Panel.Visible=false;
            if(app.index_changed==1)
                app.index_changed = length(app.pic.pic);
            else
                app.index_changed=app.index_changed-1;
            end
            if(app.Switch_3.Value=="Continuous Time Steps Changes")
                app.overlayed_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo{1,app.index_changed}.Panel.Visible=true;
            elseif(app.Switch_3.Value=="Single Time Step Changes")
                app.time_specific_changes{1,app.index_changed}.Panel.Visible=true;
                app.histo{1,app.index_changed}.Panel.Visible=true;
            end
            drawnow;
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            for i=1:length(app.pic.pic)
                app.heat{1,i}.Panel.Visible=false;
                app.canopy{1,i}.Panel.Visible=false;
            end
            if(value=="Image vs. Heatmap")
                app.heat{1,app.index_density}.Panel.Visible=true;
            elseif(value=="Canopy Illustration")
                app.canopy{1,app.index_density}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.heat{1,app.index_density}.Panel.Visible=false;
            app.canopy{1,app.index_density}.Panel.Visible=false;
            if(app.index_density==length(app.pic.pic)-1)
                app.index_density = 1;
            else
                app.index_density=app.index_density+1;
            end
            if(app.Switch_5.Value=="Image vs. Heatmap")
                app.heat{1,app.index_density}.Panel.Visible=true;
            elseif(app.Switch_5.Value=="Canopy Illustration")
                app.canopy{1,app.index_density}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.heat{1,app.index_density}.Panel.Visible=false;
            app.canopy{1,app.index_density}.Panel.Visible=false;
            if(app.index_density==1)
                app.index_density = length(app.pic.pic)-1;
            else
                app.index_density=app.index_density-1;
            end
            if(app.Switch_5.Value=="Image vs. Heatmap")
                app.heat{1,app.index_density}.Panel.Visible=true;
            elseif(app.Switch_5.Value=="Canopy Illustration")
                app.canopy{1,app.index_density}.Panel.Visible=true;
            end
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SatelliteImageChangeRecognitionUIFigure = uifigure('Visible', 'off');
            app.SatelliteImageChangeRecognitionUIFigure.Name = 'Satellite Image Change Recognition';
            app.SatelliteImageChangeRecognitionUIFigure.Icon = 'Team_Logo.png';
            app.Toolbar = uitoolbar(app.SatelliteImageChangeRecognitionUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = '126477.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.TabGroup = uitabgroup(app.SatelliteImageChangeRecognitionUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadedImagesTab = uitab(app.TabGroup);
            app.LoadedImagesTab.Title = 'Loaded Images';
            app.Button = uibutton(app.LoadedImagesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'arrows-left-slider-31125.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.LoadedImagesTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'arrows-right-slider-31125.png';
            app.Button_2.Text = '';
            app.timelineSlider = uislider(app.LoadedImagesTab);
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [];
            app.timelineSlider.MajorTickLabels = {''};
            app.timelineSlider.ValueChangedFcn = createCallbackFcn(app, @timelineSliderValueChanged, true);
            app.timelineSlider.MinorTicks = [];
            app.Switch = uiswitch(app.LoadedImagesTab, 'slider');
            app.Switch.Items = {'SURF', 'SIFT'};
            app.Switch.Enable = 'off';
            app.Switch.Visible = 'off';
            app.Switch.Value = 'SURF';
            app.RUNButton = uibutton(app.LoadedImagesTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Button_3 = uibutton(app.LoadedImagesTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.Button_3.Text = '';
            app.DeleteshownimageLabel = uilabel(app.LoadedImagesTab);
            app.DeleteshownimageLabel.Text = 'Delete shown image';
            app.Switch_6 = uiswitch(app.LoadedImagesTab, 'slider');
            app.Switch_6.Items = {'Compute Density Change (beware: slow to compute)', 'Off'};
            app.FeatureDetectionTab = uitab(app.TabGroup);
            app.FeatureDetectionTab.Title = 'Feature Detection';
            app.Button_4 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'arrows-right-slider-31125.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'arrows-left-slider-31125.png';
            app.Button_5.Text = '';
            app.Switch_2 = uiswitch(app.FeatureDetectionTab, 'slider');
            app.Switch_2.Items = {'Feature Points', 'Matched Feature Points'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Feature Points';
            app.TimeLapsOverlayDifferenceHighlightingTab = uitab(app.TabGroup);
            app.TimeLapsOverlayDifferenceHighlightingTab.Title = 'Time Laps Overlay/Difference Highlighting';
            app.Button_6 = uibutton(app.TimeLapsOverlayDifferenceHighlightingTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'arrows-right-slider-31125.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.TimeLapsOverlayDifferenceHighlightingTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'arrows-left-slider-31125.png';
            app.Button_7.Text = '';
            app.Switch_4 = uiswitch(app.TimeLapsOverlayDifferenceHighlightingTab, 'slider');
            app.Switch_4.Items = {'Timelapse Overlay', 'Timelapse Difference Highlighting'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Timelapse Overlay';
            app.ChangeDetectionTab = uitab(app.TabGroup);
            app.ChangeDetectionTab.Title = 'Change Detection';
            app.Button_8 = uibutton(app.ChangeDetectionTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'arrows-right-slider-31125.png';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.ChangeDetectionTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = 'arrows-left-slider-31125.png';
            app.Button_9.Text = '';
            app.Switch_3 = uiswitch(app.ChangeDetectionTab, 'slider');
            app.Switch_3.Items = {'Continuous Time Steps Changes', 'Single Time Step Changes'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Continuous Time Steps Changes';
            app.DensityofChangeTab = uitab(app.TabGroup);
            app.DensityofChangeTab.Title = 'Density of Change';
            app.Switch_5 = uiswitch(app.DensityofChangeTab, 'slider');
            app.Switch_5.Items = {'Canopy Illustration', 'Image vs. Heatmap'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Canopy Illustration';
            app.Button_10 = uibutton(app.DensityofChangeTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'arrows-left-slider-31125.png';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.DensityofChangeTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'arrows-right-slider-31125.png';
            app.Button_11.Text = '';
            app.Panel = uipanel(app.SatelliteImageChangeRecognitionUIFigure);
            app.Panel.Enable = 'off';
            app.Panel.Visible = 'off';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Team_Logo.png';
            app.UploadimagesLabel = uilabel(app.Panel);
            app.UploadimagesLabel.Text = 'Upload images';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = '126477.png';
            % Show the figure after all components are created
            app.SatelliteImageChangeRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Satellite_image_change_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SatelliteImageChangeRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SatelliteImageChangeRecognitionUIFigure)
        end
    end
end